classdef DemoTools < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DemoToolsUIFigure  matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        MainTab            matlab.ui.container.Tab
        FontSizer          matlab.ui.control.Button
        ZoomIt             matlab.ui.control.Button
        FFav               matlab.ui.control.Button
        ZFav               matlab.ui.control.Button
        Cleaner            matlab.ui.control.Button
        CFav               matlab.ui.control.Button
        RT                 matlab.ui.control.Button
        RTFav              matlab.ui.control.Button
        AllFav             matlab.ui.control.Button
        Organizer          matlab.ui.control.Button
        OFav               matlab.ui.control.Button
        Trainer            matlab.ui.control.Button
        TFav               matlab.ui.control.Button
        UpdateTab          matlab.ui.container.Tab
        CV                 matlab.ui.control.Label
        IV                 matlab.ui.control.Label
        Update             matlab.ui.control.Button
    end

    
    properties (Access = private)
        U % Updater
    end
    
    methods (Access = private)
        
        function addFav(~, label, icon, code)
            fc = com.mathworks.mlwidgets.favoritecommands.FavoriteCommands.getInstance();
            newFav = com.mathworks.mlwidgets.favoritecommands.FavoriteCommandProperties();
            newFav.setLabel(label);
            newFav.setCategoryLabel("DEMO TOOLS");
            p = fileparts(mfilename('fullpath'));
            newFav.setIconPath(p);
            newFav.setIconName(icon);
            newFav.setCode(code);
            newFav.setIsOnQuickToolBar(true);
            fc.addCommand(newFav);
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.U = DemoToolsUpdater();
            app.UpdateButtonPushed();
        end

        % Button pushed function: ZoomIt
        function ZoomItButtonPushed(app, event)
            ZoomIt;
        end

        % Button pushed function: FontSizer
        function FontSizerButtonPushed(app, event)
            FontSizer;
        end

        % Button pushed function: FFav
        function FFavButtonPushed(app, event)
            app.addFav("Change font size", "fontsizer-icon.png", "FontSizer");
        end

        % Button pushed function: ZFav
        function ZFavButtonPushed(app, event)
            app.addFav("ZoomIt", "zoomit-icon.png", "ZoomIt");
        end

        % Button pushed function: CFav
        function CFavButtonPushed(app, event)
            app.addFav("Clear", "cleaner-icon.png", "Cleaner");
        end

        % Button pushed function: RTFav
        function RTFavButtonPushed(app, event)
            app.addFav("SLRT Explorer", "slrt-icon.png", "slrtexplr");
        end

        % Button pushed function: RT
        function RTButtonPushed(app, event)
            slrtexplr
        end

        % Button pushed function: Cleaner
        function CleanerButtonPushed(app, event)
            Cleaner
        end

        % Button pushed function: AllFav
        function AllFavButtonPushed(app, event)
            app.RTFavButtonPushed();
            app.TFavButtonPushed();
            app.ZFavButtonPushed();
            app.FFavButtonPushed();
            app.OFavButtonPushed();
            app.CFavButtonPushed();
        end

        % Button pushed function: Organizer
        function OrganizerButtonPushed(app, event)
            Organizer
        end

        % Button pushed function: OFav
        function OFavButtonPushed(app, event)
            app.addFav("Projects organizer", "organizer-icon.png", "Organizer");
        end

        % Button pushed function: Trainer
        function TrainerButtonPushed(app, event)
            Trainer
        end

        % Button pushed function: TFav
        function TFavButtonPushed(app, event)
            app.addFav("Trainer", "trainer-icon.png", "Trainer");
        end

        % Button pushed function: Update
        function UpdateButtonPushed(app, event)
            if app.Update.Text == "Check"
                [cv, iv] = app.U.ver();
                app.CV.Text = "Current Version: " + cv;
                app.IV.Text = "Latest Version: " + iv;
                if isempty(iv) || isequal(cv, iv)
                    app.Update.Text = 'Check';
                else
                    app.Update.Text = 'Update';
                end
            else
                app.U.update();
                delete(app);
                DemoTools
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DemoToolsUIFigure = uifigure;
            app.DemoToolsUIFigure.Name = 'Demo Tools';
            app.TabGroup = uitabgroup(app.DemoToolsUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.FontSizer = uibutton(app.MainTab, 'push');
            app.FontSizer.ButtonPushedFcn = createCallbackFcn(app, @FontSizerButtonPushed, true);
            app.FontSizer.Icon = 'fontsizer-icon.png';
            app.FontSizer.Text = 'FontSizer';
            app.ZoomIt = uibutton(app.MainTab, 'push');
            app.ZoomIt.ButtonPushedFcn = createCallbackFcn(app, @ZoomItButtonPushed, true);
            app.ZoomIt.Icon = 'zoomit-icon.png';
            app.ZoomIt.Text = 'ZoomIt';
            app.FFav = uibutton(app.MainTab, 'push');
            app.FFav.ButtonPushedFcn = createCallbackFcn(app, @FFavButtonPushed, true);
            app.FFav.Icon = 'favorites.png';
            app.FFav.Tooltip = {'Add to Favorites'};
            app.FFav.Text = '';
            app.ZFav = uibutton(app.MainTab, 'push');
            app.ZFav.ButtonPushedFcn = createCallbackFcn(app, @ZFavButtonPushed, true);
            app.ZFav.Icon = 'favorites.png';
            app.ZFav.Tooltip = {'Add to Favorites'};
            app.ZFav.Text = '';
            app.Cleaner = uibutton(app.MainTab, 'push');
            app.Cleaner.ButtonPushedFcn = createCallbackFcn(app, @CleanerButtonPushed, true);
            app.Cleaner.Icon = 'cleaner-icon.png';
            app.Cleaner.Text = 'Cleaner';
            app.CFav = uibutton(app.MainTab, 'push');
            app.CFav.ButtonPushedFcn = createCallbackFcn(app, @CFavButtonPushed, true);
            app.CFav.Icon = 'favorites.png';
            app.CFav.Tooltip = {'Add to Favorites'};
            app.CFav.Text = '';
            app.RT = uibutton(app.MainTab, 'push');
            app.RT.ButtonPushedFcn = createCallbackFcn(app, @RTButtonPushed, true);
            app.RT.Icon = 'slrt-icon.png';
            app.RT.Text = 'slrtexplr';
            app.RTFav = uibutton(app.MainTab, 'push');
            app.RTFav.ButtonPushedFcn = createCallbackFcn(app, @RTFavButtonPushed, true);
            app.RTFav.Icon = 'favorites.png';
            app.RTFav.Tooltip = {'Add to Favorites'};
            app.RTFav.Text = '';
            app.AllFav = uibutton(app.MainTab, 'push');
            app.AllFav.ButtonPushedFcn = createCallbackFcn(app, @AllFavButtonPushed, true);
            app.AllFav.Icon = 'favorites.png';
            app.AllFav.Tooltip = {'Add to Favorites'};
            app.AllFav.Text = 'All';
            app.Organizer = uibutton(app.MainTab, 'push');
            app.Organizer.ButtonPushedFcn = createCallbackFcn(app, @OrganizerButtonPushed, true);
            app.Organizer.Icon = 'Organizer-icon.png';
            app.Organizer.Text = 'Organizer';
            app.OFav = uibutton(app.MainTab, 'push');
            app.OFav.ButtonPushedFcn = createCallbackFcn(app, @OFavButtonPushed, true);
            app.OFav.Icon = 'favorites.png';
            app.OFav.Tooltip = {'Add to Favorites'};
            app.OFav.Text = '';
            app.Trainer = uibutton(app.MainTab, 'push');
            app.Trainer.ButtonPushedFcn = createCallbackFcn(app, @TrainerButtonPushed, true);
            app.Trainer.Icon = 'trainer-icon.png';
            app.Trainer.Text = 'Trainer';
            app.TFav = uibutton(app.MainTab, 'push');
            app.TFav.ButtonPushedFcn = createCallbackFcn(app, @TFavButtonPushed, true);
            app.TFav.Icon = 'favorites.png';
            app.TFav.Tooltip = {'Add to Favorites'};
            app.TFav.Text = '';
            app.UpdateTab = uitab(app.TabGroup);
            app.UpdateTab.Title = 'Update';
            app.CV = uilabel(app.UpdateTab);
            app.CV.Text = 'Current Version: ';
            app.IV = uilabel(app.UpdateTab);
            app.IV.Text = 'Latest Version: ';
            app.Update = uibutton(app.UpdateTab, 'push');
            app.Update.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.Update.Text = 'Check';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DemoTools

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DemoToolsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DemoToolsUIFigure)
        end
    end
end