classdef FontSizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FontSizerUIFigure  matlab.ui.Figure
        Slider             matlab.ui.control.Slider
        FontSize           matlab.ui.control.NumericEditField
        SetNormal          matlab.ui.control.Button
        SetLARGE           matlab.ui.control.Button
        Favorites          matlab.ui.control.Button
    end

    
    properties (Access = private)
        NORMAL = 14 % Normal font size
        LARGE = 28 % Large font size
        s = settings % MATLAB Settings
    end
    
    methods (Access = private)
        
        function setfont(app, size)
            app.s.matlab.fonts.codefont.Size.PersonalValue = size;
        end
        
        function size = getfont(app)
            size = double(app.s.matlab.fonts.codefont.Size.PersonalValue);
        end
        
        function preset(app, size)
            app.setfont(size);
            app.Slider.Value = size;
            app.FontSize.Value = size;
            delete(app);
        end
        
        function updateFontSize(app, value)
            app.FontSize.Value = value;
            app.FontSizeValueChanged();
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FontSizerUIFigure.Position(2) = 600;
            app.Slider.MinorTicks = [];
            app.Slider.MajorTicks = app.Slider.Limits(1) : 2 : app.Slider.Limits(2);
            value = app.getfont();
            app.Slider.Value = value;
            app.updateFontSize(value);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            value = round(value);
            app.Slider.Value = value;
            app.updateFontSize(value);
        end

        % Value changed function: FontSize
        function FontSizeValueChanged(app, event)
            value = app.FontSize.Value;
            app.setfont(value);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            value = round(event.Value);
            app.updateFontSize(value);
        end

        % Button pushed function: SetLARGE
        function SetLARGEButtonPushed(app, event)
            app.preset(app.LARGE);
        end

        % Button pushed function: SetNormal
        function SetNormalButtonPushed(app, event)
            app.preset(app.NORMAL);
        end

        % Button pushed function: Favorites
        function FavoritesButtonPushed(app, event)
            fc = com.mathworks.mlwidgets.favoritecommands.FavoriteCommands.getInstance();
            newFav = com.mathworks.mlwidgets.favoritecommands.FavoriteCommandProperties();
            newFav.setLabel("Change font size");
            p = fileparts(mfilename('fullpath'));
            newFav.setIconPath(p);
            newFav.setIconName("fontsizer-icon.png");
            newFav.setCode("FontSizer");
            newFav.setIsOnQuickToolBar(true);
            fc.addCommand(newFav);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FontSizerUIFigure = uifigure;
            app.FontSizerUIFigure.Name = 'Font Sizer';
            app.Slider = uislider(app.FontSizerUIFigure);
            app.Slider.Limits = [10 40];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 10;
            app.FontSize = uieditfield(app.FontSizerUIFigure, 'numeric');
            app.FontSize.Limits = [10 40];
            app.FontSize.ValueDisplayFormat = '%.0f';
            app.FontSize.ValueChangedFcn = createCallbackFcn(app, @FontSizeValueChanged, true);
            app.FontSize.Value = 10;
            app.SetNormal = uibutton(app.FontSizerUIFigure, 'push');
            app.SetNormal.ButtonPushedFcn = createCallbackFcn(app, @SetNormalButtonPushed, true);
            app.SetNormal.Text = 'Normal';
            app.SetLARGE = uibutton(app.FontSizerUIFigure, 'push');
            app.SetLARGE.ButtonPushedFcn = createCallbackFcn(app, @SetLARGEButtonPushed, true);
            app.SetLARGE.Text = 'LARGE';
            app.Favorites = uibutton(app.FontSizerUIFigure, 'push');
            app.Favorites.ButtonPushedFcn = createCallbackFcn(app, @FavoritesButtonPushed, true);
            app.Favorites.Icon = 'favorites.png';
            app.Favorites.Tooltip = {'Add to Favorites'};
            app.Favorites.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FontSizer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FontSizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FontSizerUIFigure)
        end
    end
end