classdef Organizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OrganizerUIFigure   matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        ProjectsTab         matlab.ui.container.Tab
        UITable             matlab.ui.control.Table
        Open                matlab.ui.control.Button
        Delete              matlab.ui.control.Button
        Up                  matlab.ui.control.Button
        Down                matlab.ui.control.Button
        Open1               matlab.ui.control.Button
        Open2               matlab.ui.control.Button
        Open3               matlab.ui.control.Button
        Open4               matlab.ui.control.Button
        Open5               matlab.ui.control.Button
        AddTab              matlab.ui.container.Tab
        NameEditFieldLabel  matlab.ui.control.Label
        Name                matlab.ui.control.EditField
        PathEditFieldLabel  matlab.ui.control.Label
        Path                matlab.ui.control.EditField
        Add                 matlab.ui.control.Button
        InitcodeLabel       matlab.ui.control.Label
        Init                matlab.ui.control.EditField
        Refresh             matlab.ui.control.Button
        SettingsTab         matlab.ui.container.Tab
        AutoClean           matlab.ui.control.CheckBox
        InitDev             matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        root % root path
        rname % root dir name
        L % list of projects
        S % settings
        Lsel = 1 % Selection in table
    end
    
    methods (Access = private)
        
        function [fpath, fname] = genfpath(app, add)
            %Generate data file name
            if nargin < 2
                add = 'data';
            end
            fname = app.rname + "_" + add + ".mat";
            fpath = fullfile(app.root, fname);
        end
        
        
        function loadData(app)
            %Load list of projects from file
            fpath = app.genfpath();
            if isfile(fpath)
                r = load(fpath);
                app.L = r.L;
                app.S = r.S;
            else
                vnames = {'N' 'Name' 'Path' 'Init'};
                app.L = cell2table(cell(0, length(vnames)), 'VariableNames', vnames);
                app.changeSettings();
            end
        end
        
        function saveData(app)
            %Save list of projects
            fpath = app.genfpath();
            L = app.L;
            S = app.S;
            save(fpath, 'L', 'S');
        end
        
        function addProject(app, name, path, init)
            %Add project to list
            app.L = [app.L; {0 name path init}];
            app.apply();
        end
        
        function deleteProject(app)
            %Delete project from list
            if app.Lsel <= height(app.L)
                app.L(app.Lsel, :) = [];
                app.apply();
            end
        end
        
        function moveProject(app, dir)
            %Move row
            iold = app.Lsel;
            inew = iold + dir;
            if inew < 1
                inew = height(app.L);
            elseif inew > height(app.L)
                inew = 1;
            end
            app.L([inew iold], :) = app.L([iold inew], :);
            app.apply();
        end
        
        function openProject(app, n)
            %Open project
            if (n >= 1) && (n <= height(app.L))
                cd(app.L.Path{n});
                if app.S.AutoClean
                    evalin('base', 'Cleaner');
                end
                evalin('base', app.L.Init{n});
                delete(app);
            end
        end
        
        function renum(app)
            %Reassign numbers of project
            app.L.N = (1 : height(app.L))';
        end
        
        function updateTable(app)
            %Draw list of projects
            if isempty(app.L)
                app.UITable.Data = [];
            else
                app.UITable.Data = app.L;
                app.UITable.ColumnName = app.L.Properties.VariableNames;
            end
        end
    
        function updateAdd(app)
            %Refresh Add Tab            
            app.Path.Value = pwd;
            [~, app.Name.Value] = fileparts(app.Path.Value);
            if app.InitDev.Value && isfile('dev_on.m')
                app.Init.Value = 'dev_on';
            else
                app.Init.Value = '';
            end
        end
        
        function updateSettings(app)
            %Update settings
            app.AutoClean.Value = app.S.AutoClean;
            app.InitDev.Value = app.S.InitDev;
        end
        
        function changeSettings(app)
            %Update settings
            app.S.AutoClean = app.AutoClean.Value;
            app.S.InitDev = app.InitDev.Value;
        end
        
        function apply(app)
            %Apply changes
            app.renum();
            app.saveData();
            app.updateTable();
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.root = fileparts(mfilename('fullpath'));
            [~, rname] = fileparts(app.root);
            rname = matlab.lang.makeValidName(rname);
            app.rname = rname;
            app.loadData();
            app.updateTable();
            app.updateSettings();
            app.updateAdd();
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.Lsel = event.Indices(1);
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            app.addProject(app.Name.Value, app.Path.Value, app.Init.Value);
        end

        % Button pushed function: Delete
        function DeleteButtonPushed(app, event)
            app.deleteProject();
        end

        % Button pushed function: Up
        function UpButtonPushed(app, event)
            app.moveProject(-1);
        end

        % Button pushed function: Down
        function DownButtonPushed(app, event)
            app.moveProject(1);
        end

        % Button pushed function: Open
        function OpenButtonPushed(app, event)
            app.openProject(app.Lsel);
        end

        % Button pushed function: Open1
        function Open1ButtonPushed(app, event)
            app.openProject(1);
        end

        % Button pushed function: Open2
        function Open2ButtonPushed(app, event)
            app.openProject(2);
        end

        % Button pushed function: Open3
        function Open3ButtonPushed(app, event)
            app.openProject(3);
        end

        % Button pushed function: Open4
        function Open4ButtonPushed(app, event)
            app.openProject(4);
        end

        % Button pushed function: Open5
        function Open5ButtonPushed(app, event)
            app.openProject(5);
        end

        % Value changed function: AutoClean, InitDev
        function SettingsChanged(app, event)
            app.changeSettings();
            app.saveData();
        end

        % Button pushed function: Refresh
        function RefreshButtonPushed(app, event)
            app.updateAdd();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OrganizerUIFigure = uifigure;
            app.OrganizerUIFigure.Name = 'Organizer';
            app.TabGroup = uitabgroup(app.OrganizerUIFigure);
            app.ProjectsTab = uitab(app.TabGroup);
            app.ProjectsTab.Title = 'Projects';
            app.UITable = uitable(app.ProjectsTab);
            app.UITable.ColumnName = {'N'; 'Name'; 'Path'; 'Init'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Open = uibutton(app.ProjectsTab, 'push');
            app.Open.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.Open.Icon = 'icon-open.png';
            app.Open.Tooltip = {'Open selected'};
            app.Open.Text = '';
            app.Delete = uibutton(app.ProjectsTab, 'push');
            app.Delete.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.Delete.Icon = 'icon-bin.png';
            app.Delete.Tooltip = {'Delete'};
            app.Delete.Text = '';
            app.Up = uibutton(app.ProjectsTab, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.Up.Icon = 'icon-up.png';
            app.Up.Tooltip = {'Move up'};
            app.Up.Text = '';
            app.Down = uibutton(app.ProjectsTab, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.Down.Icon = 'icon-down.png';
            app.Down.Tooltip = {'Move down'};
            app.Down.Text = '';
            app.Open1 = uibutton(app.ProjectsTab, 'push');
            app.Open1.ButtonPushedFcn = createCallbackFcn(app, @Open1ButtonPushed, true);
            app.Open1.Tooltip = {'Open 1'};
            app.Open1.Text = '1';
            app.Open2 = uibutton(app.ProjectsTab, 'push');
            app.Open2.ButtonPushedFcn = createCallbackFcn(app, @Open2ButtonPushed, true);
            app.Open2.Tooltip = {'Open 2'};
            app.Open2.Text = '2';
            app.Open3 = uibutton(app.ProjectsTab, 'push');
            app.Open3.ButtonPushedFcn = createCallbackFcn(app, @Open3ButtonPushed, true);
            app.Open3.Tooltip = {'Open 3'};
            app.Open3.Text = '3';
            app.Open4 = uibutton(app.ProjectsTab, 'push');
            app.Open4.ButtonPushedFcn = createCallbackFcn(app, @Open4ButtonPushed, true);
            app.Open4.Tooltip = {'Open 4'};
            app.Open4.Text = '4';
            app.Open5 = uibutton(app.ProjectsTab, 'push');
            app.Open5.ButtonPushedFcn = createCallbackFcn(app, @Open5ButtonPushed, true);
            app.Open5.Tooltip = {'Open 5'};
            app.Open5.Text = '5';
            app.AddTab = uitab(app.TabGroup);
            app.AddTab.Title = 'Add';
            app.NameEditFieldLabel = uilabel(app.AddTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.Name = uieditfield(app.AddTab, 'text');
            app.PathEditFieldLabel = uilabel(app.AddTab);
            app.PathEditFieldLabel.Text = 'Path';
            app.Path = uieditfield(app.AddTab, 'text');
            app.Add = uibutton(app.AddTab, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Icon = 'icon-add.png';
            app.Add.Tooltip = {'Add'};
            app.Add.Text = '';
            app.InitcodeLabel = uilabel(app.AddTab);
            app.InitcodeLabel.Text = 'Init code';
            app.Init = uieditfield(app.AddTab, 'text');
            app.Refresh = uibutton(app.AddTab, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.Refresh.Icon = 'icon-refresh.png';
            app.Refresh.Tooltip = {'Refresh'};
            app.Refresh.Text = '';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.AutoClean = uicheckbox(app.SettingsTab);
            app.AutoClean.ValueChangedFcn = createCallbackFcn(app, @SettingsChanged, true);
            app.AutoClean.Text = 'Auto run Cleaner when open';
            app.AutoClean.Value = true;
            app.InitDev = uicheckbox(app.SettingsTab);
            app.InitDev.ValueChangedFcn = createCallbackFcn(app, @SettingsChanged, true);
            app.InitDev.Text = 'Add dev_on to init if exists';
            app.InitDev.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Organizer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OrganizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OrganizerUIFigure)
        end
    end
end