classdef Trainer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrainerUIFigure  matlab.ui.Figure
        MainTabs         matlab.ui.container.TabGroup
        CoursesTab       matlab.ui.container.Tab
        Courses          matlab.ui.control.ListBox
        TabGroup2        matlab.ui.container.TabGroup
        TasksTab         matlab.ui.container.Tab
        OpenSlides       matlab.ui.control.Button
        OpenPrint        matlab.ui.control.Button
        OpenResources    matlab.ui.control.Button
        Start            matlab.ui.control.Button
        Install          matlab.ui.control.Button
        VersionLabel     matlab.ui.control.Label
        AdditionalTab    matlab.ui.container.Tab
        OpenWork         matlab.ui.control.Button
        OpenArchive      matlab.ui.control.Button
        CdWork           matlab.ui.control.Button
        DeleteArchive    matlab.ui.control.Button
        DeleteWork       matlab.ui.control.Button
        USBTab           matlab.ui.container.Tab
        SettingsTab      matlab.ui.container.Tab
        PathsPanel       matlab.ui.container.Panel
        ArchiveLabel     matlab.ui.control.Label
        Archive          matlab.ui.control.EditField
        WorkLabel        matlab.ui.control.Label
        Work             matlab.ui.control.EditField
        Pathtoafolderwhere_CoursezipfilesarestoredLabel  matlab.ui.control.Label
        PathtoafolderwhereyouwillworkwithMATLABfilesLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        S % Storage
    end
    
    methods (Access = private)
        
        function readCourses(app)
            if ~isempty(app.Archive)
                fs = struct2table(dir(fullfile(app.Archive.Value, '*_Course.zip')));
                if ~isempty(fs)
                    fs = sortrows(fs, 'name');
                    dates = datetime(fs.datenum, 'ConvertFrom', 'datenum');
                    dates = string(datestr(dates, 'dd.mm.yyyy'));
                    names = string(erase(fs.name, '_Course.zip'));
                    app.Courses.Items = compose("%s\t\t(%s)", names, dates);
                    app.Courses.ItemsData = fullfile(app.Archive.Value, fs.name);
                    app.setCurrentCourse();
                    app.CoursesValueChanged();
                else
                    app.Courses.Items = {};
                end
            end
        end
    
        function setCurrentCourse(app)
            [~, course] = fileparts(pwd);
            course = lower(course);
            courses = split(app.Courses.Items');
            courses = lower(courses(:, 1));
            isc = find(ismember(courses, course));
            if isempty(isc)
                isc = 1;
            end
            app.Courses.Value = app.Courses.ItemsData{isc(1)};
        end
        
        function folder = getafolder(app)
            fpath = app.Courses.Value;
            folder = erase(fpath, '.zip');
        end
        
        function folder = getwfolder(app)
            folder = fullfile(app.Work.Value, app.getcourse());
        end
        
        function course = getcourse(app)
            [~, course] = fileparts(app.Courses.Value);
            course = erase(course, '_Course');
            course = lower(course);
        end
        
        function unpackArchive(app, force)
            if nargin < 2
                force = 0;
            end
            fpath = app.Courses.Value;
            dirpath = app.getafolder();
            app.unpack(fpath, dirpath, force);
            app.CoursesValueChanged();
        end
        
        function unpackWork(app, force)
            if nargin < 2
                force = 0;
            end
            app.unpackArchive();
            fpath = fullfile(app.getafolder(), app.getcourse + ".zip");
            dirpath = app.Work.Value();
            if force
                app.rm(app.getwfolder());
            end
            app.unpack(fpath, dirpath, force);
            app.CoursesValueChanged();
        end
        
        function unpack(app, fpath, dirpath, force)
            [~, name] = fileparts(fpath);
            if ~isfolder(fullfile(dirpath)) || force
                wb = app.waitbar('Извлечение файлов...');
                unzip(fpath, dirpath);
                close(wb);
            end
        end
    
        function rm(app, path)
            if isfolder(path)
                wb = app.waitbar('Удаление файлов...');
                rmdir(path, 's');
                close(wb);
                app.CoursesValueChanged();
            end
        end
        
        function openArchive(app, folder)
            if nargin < 2
                folder = '';
            end
            app.unpackArchive();
            winopen(fullfile(app.getafolder(), folder));
        end
        
        function openWork(app, iscd)
            if nargin < 2
                iscd = false;
            end
            app.unpackWork();
            if iscd
                cd(app.getwfolder());
            else
                winopen(app.getwfolder());
            end
        end
        
        function addToPath(app)
            addScript = dir('add*toPath.m');
            if ~isempty(addScript)
                run(addScript(1).name);
            end
        end
        
        function wb = waitbar(app, title)
            wb = uiprogressdlg(app.TrainerUIFigure, 'Title', title, 'Indeterminate', 'on');
        end
        
        
        function v = getVersion(app)
            print = fullfile(app.getafolder(), 'print');
            if isfolder(print) && license('test', 'text_analytics_toolbox')
                fs = struct2table(dir(fullfile(print, '*.pdf')));
                toc = fs.name(contains(fs.name, '_toc_'));
                txt = extractFileText(fullfile(print, char(toc)), 'Page', 1);
                v = split(txt);
                v = v(end);
            else
                v = "";
            end
        end
    
        function welcome(app)
            if isempty(app.Archive.Value) || isempty(app.Work.Value)
                app.MainTabs.SelectedTab = app.SettingsTab;
                uialert(app.TrainerUIFigure, ['Hi!' newline 'Please set up paths'], 'Setup', 'icon', 'icon-gear.png')
            end
        end
    
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.S = DemoToolsStorage('Trainer_data');
            app.Archive.Value = app.S.get('archive', 'char');
            app.Work.Value = app.S.get('work', 'char');
            app.readCourses();
            app.welcome();
        end

        % Value changed function: Archive
        function ArchiveValueChanged(app, event)
            app.S.set('archive', app.Archive.Value);
            app.S.save();
            app.readCourses();
        end

        % Callback function
        function UnpackButtonPushed(app, event)
            app.unpackArchive(1);
        end

        % Button pushed function: OpenArchive
        function OpenArchivePushed(app, event)
            app.openArchive();
        end

        % Button pushed function: OpenSlides
        function OpenSlidesButtonPushed(app, event)
            app.openArchive('slides');
        end

        % Button pushed function: OpenPrint
        function OpenPrintButtonPushed(app, event)
            app.openArchive('print');
        end

        % Button pushed function: OpenResources
        function OpenResourcesButtonPushed(app, event)
            app.openArchive('resources');
        end

        % Value changed function: Work
        function WorkValueChanged(app, event)
            app.S.set('work', app.Work.Value);
            app.S.save();
        end

        % Button pushed function: Install
        function InstallButtonPushed(app, event)
            app.unpackWork(1);
        end

        % Button pushed function: OpenWork
        function OpenWorkButtonPushed(app, event)
            app.openWork();
        end

        % Button pushed function: Start
        function StartButtonPushed(app, event)
            app.openWork(1);
            app.addToPath();
            delete(app);
        end

        % Value changed function: Courses
        function CoursesValueChanged(app, event)
            if isfolder(app.getwfolder()) && isfolder(app.getafolder())
                txt = 'Reinstall';
            else
                txt = 'Install';
            end
            app.Install.Text = txt;
            app.VersionLabel.Text = app.getVersion();
        end

        % Button pushed function: CdWork
        function CdWorkButtonPushed(app, event)
            app.openWork(1);
        end

        % Selection change function: MainTabs
        function MainTabsSelectionChanged(app, event)
            app.welcome();
        end

        % Button pushed function: DeleteArchive
        function DeleteArchiveButtonPushed(app, event)
            app.rm(app.getafolder());
        end

        % Button pushed function: DeleteWork
        function DeleteWorkButtonPushed(app, event)
            app.rm(app.getwfolder());
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrainerUIFigure = uifigure;
            app.TrainerUIFigure.Name = 'Trainer';
            app.MainTabs = uitabgroup(app.TrainerUIFigure);
            app.MainTabs.SelectionChangedFcn = createCallbackFcn(app, @MainTabsSelectionChanged, true);
            app.CoursesTab = uitab(app.MainTabs);
            app.CoursesTab.Title = 'Courses';
            app.Courses = uilistbox(app.CoursesTab);
            app.Courses.Items = {};
            app.Courses.ValueChangedFcn = createCallbackFcn(app, @CoursesValueChanged, true);
            app.Courses.Value = {};
            app.TabGroup2 = uitabgroup(app.CoursesTab);
            app.TasksTab = uitab(app.TabGroup2);
            app.TasksTab.Title = 'Tasks';
            app.OpenSlides = uibutton(app.TasksTab, 'push');
            app.OpenSlides.ButtonPushedFcn = createCallbackFcn(app, @OpenSlidesButtonPushed, true);
            app.OpenSlides.Icon = 'icon-slides.png';
            app.OpenSlides.Text = 'slides';
            app.OpenPrint = uibutton(app.TasksTab, 'push');
            app.OpenPrint.ButtonPushedFcn = createCallbackFcn(app, @OpenPrintButtonPushed, true);
            app.OpenPrint.Icon = 'icon-book.png';
            app.OpenPrint.Text = 'print';
            app.OpenResources = uibutton(app.TasksTab, 'push');
            app.OpenResources.ButtonPushedFcn = createCallbackFcn(app, @OpenResourcesButtonPushed, true);
            app.OpenResources.Icon = 'icon-work.png';
            app.OpenResources.Text = 'resources';
            app.Start = uibutton(app.TasksTab, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Start.Icon = 'icon-start.png';
            app.Start.Text = 'Start';
            app.Install = uibutton(app.TasksTab, 'push');
            app.Install.ButtonPushedFcn = createCallbackFcn(app, @InstallButtonPushed, true);
            app.Install.Icon = 'icon-install.png';
            app.Install.Text = 'Install';
            app.VersionLabel = uilabel(app.TasksTab);
            app.VersionLabel.Text = 'Version';
            app.AdditionalTab = uitab(app.TabGroup2);
            app.AdditionalTab.Title = 'Additional';
            app.OpenWork = uibutton(app.AdditionalTab, 'push');
            app.OpenWork.ButtonPushedFcn = createCallbackFcn(app, @OpenWorkButtonPushed, true);
            app.OpenWork.Text = 'Open Work';
            app.OpenArchive = uibutton(app.AdditionalTab, 'push');
            app.OpenArchive.ButtonPushedFcn = createCallbackFcn(app, @OpenArchivePushed, true);
            app.OpenArchive.Text = 'Open Archive';
            app.CdWork = uibutton(app.AdditionalTab, 'push');
            app.CdWork.ButtonPushedFcn = createCallbackFcn(app, @CdWorkButtonPushed, true);
            app.CdWork.Text = 'cd to Work';
            app.DeleteArchive = uibutton(app.AdditionalTab, 'push');
            app.DeleteArchive.ButtonPushedFcn = createCallbackFcn(app, @DeleteArchiveButtonPushed, true);
            app.DeleteArchive.Text = 'Delete Archive';
            app.DeleteWork = uibutton(app.AdditionalTab, 'push');
            app.DeleteWork.ButtonPushedFcn = createCallbackFcn(app, @DeleteWorkButtonPushed, true);
            app.DeleteWork.Text = 'Delete Work';
            app.USBTab = uitab(app.TabGroup2);
            app.USBTab.Title = 'USB';
            app.SettingsTab = uitab(app.MainTabs);
            app.SettingsTab.Title = 'Settings';
            app.PathsPanel = uipanel(app.SettingsTab);
            app.PathsPanel.Title = 'Paths';
            app.ArchiveLabel = uilabel(app.PathsPanel);
            app.ArchiveLabel.Text = 'Archive';
            app.Archive = uieditfield(app.PathsPanel, 'text');
            app.Archive.ValueChangedFcn = createCallbackFcn(app, @ArchiveValueChanged, true);
            app.WorkLabel = uilabel(app.PathsPanel);
            app.WorkLabel.Text = 'Work';
            app.Work = uieditfield(app.PathsPanel, 'text');
            app.Work.ValueChangedFcn = createCallbackFcn(app, @WorkValueChanged, true);
            app.Pathtoafolderwhere_CoursezipfilesarestoredLabel = uilabel(app.PathsPanel);
            app.Pathtoafolderwhere_CoursezipfilesarestoredLabel.Text = 'Path to a folder where  ''*_Course.zip''  files are stored';
            app.PathtoafolderwhereyouwillworkwithMATLABfilesLabel = uilabel(app.PathsPanel);
            app.PathtoafolderwhereyouwillworkwithMATLABfilesLabel.Text = 'Path to a folder where you will work with MATLAB files';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Trainer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrainerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrainerUIFigure)
        end
    end
end